classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        GridLayout              matlab.ui.container.GridLayout
        LeftPanel               matlab.ui.container.Panel
        IdentifyOutliersButton  matlab.ui.control.Button
        LoadDataButton          matlab.ui.control.Button
        RightPanel              matlab.ui.container.Panel
        UIAxes                  matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            % Open a dialog box for the user to select a .csv file.
            [fileName, filePath] = uigetfile('*.csv', 'Select a Reactor Data CSV file');
            
            % Check if the user clicked "Cancel" on the file dialog.
            if isequal(fileName, 0)
                return;
            end
            
            % Create the full path to the selected file.
            fullPath = fullfile(filePath, fileName);
            
            try
                % Read the data from the CSV file into a table.
                data = readtable(fullPath);
                
                % --- START OF MODIFIED COLUMN FINDING LOGIC ---
                
                % Get all column names from the table
                colNames = data.Properties.VariableNames;
                
                % Find the first column containing 'Time', 'Temp', and 'Pressure' (case-insensitive)
                timeColIdx = find(contains(colNames, 'Time', 'IgnoreCase', true), 1);
                tempColIdx = find(contains(colNames, 'Temp', 'IgnoreCase', true), 1);
                pressureColIdx = find(contains(colNames, 'Pressure', 'IgnoreCase', true), 1);
                
                % Check if all required columns were found
                if ~isempty(timeColIdx) && ~isempty(tempColIdx) && ~isempty(pressureColIdx)
                    
                    % Extract data using the found column indices
                    timeData = data{:, timeColIdx};
                    tempData = data{:, tempColIdx};
                    pressureData = data{:, pressureColIdx};
                    
                    % --- END OF MODIFIED COLUMN FINDING LOGIC ---
                    
                    % --- DUAL-AXIS PLOTTING CODE (Now uses flexible data variables) ---
            
                    % Clear the axes before drawing a new plot
                    cla(app.UIAxes, 'reset');
            
                    % Activate the left y-axis for Temperature
                    yyaxis(app.UIAxes, 'left');
                    plot(app.UIAxes, timeData, tempData, 'b-', 'LineWidth', 1.5);
                    
                    % Label the left axis and set its color
                    ylabel(app.UIAxes, 'Temperature (°C)', 'Color', 'b');
                    app.UIAxes.YColor = 'b';
            
                    % Activate the right y-axis for Pressure
                    yyaxis(app.UIAxes, 'right');
                    % CORRECTED THIS LINE: Plot pressureData against timeData
                    plot(app.UIAxes, timeData, pressureData, 'r-', 'LineWidth', 1.5);
                    
                    % Label the right axis and set its color
                    ylabel(app.UIAxes, 'Pressure (Pa)', 'Color', 'r');
                    app.UIAxes.YColor = 'r';
            
                    % Add title, x-axis label, legend, and grid
                    safeFileName = strrep(fileName, '_', ' ');
                    title(app.UIAxes, ['Data from ', safeFileName]);
                    xlabel(app.UIAxes, 'Time (sec)');
                    legend(app.UIAxes, 'Temperature', 'Pressure', 'Location', 'northwest');
                    grid(app.UIAxes, 'on');
        
                else
                    % If the file is missing required columns, show an alert to the user.
                    missingColsMsg = sprintf('Selected file must have columns containing the words "Time", "Temp", and "Pressure".');
                    uialert(app.UIFigure, missingColsMsg, 'Invalid File Format');
                end
                
            catch ME
                % If any error occurs (e.g., file is not a valid CSV),
                % display an error message in an alert dialog.
                uialert(app.UIAxes, ['Error loading file: ' ME.message], 'Error');
            end
    
        end

        % Button pushed function: IdentifyOutliersButton
        function IdentifyOutliersButtonPushed(app, event)
            % Ensure there is data plotted on the UIAxes
            if isempty(app.UIAxes.Children)
                uialert(app.UIFigure, 'No data to analyze. Please plot data first.', 'No Data');
                return;
            end
            
            % --- Step 1: Get Data from the Plot ---
            % Assumes the most recent plot is the one to be analyzed.
            lineObject = findobj(app.UIAxes.Children, 'Type', 'line');
            
            if isempty(lineObject)
                uialert(app.UIFigure, 'Could not find a line plot to analyze.', 'Plot Not Found');
                return;
            end
        
            % Extract X and Y data from the first line object found
            x_data = lineObject(1).XData;
            y_data = lineObject(1).YData;
            
            % --- Step 2: Identify Outliers using a Moving Average Method ---
            % This method is more effective for data with a clear trend, as it identifies
            % points that deviate from the local average, not just the global extremes.
            
            % -- Parameters to Tune --
            window_size = 20;     % The number of data points to include in the moving average window.
                                  % A larger window creates a smoother trend line.
            std_multiplier = 2.5; % The number of standard deviations from the trend line to be 
                                  % considered an outlier. A smaller value is stricter.
        
            % Calculate the moving average to establish the local trend of the data
            moving_avg = movmean(y_data, window_size);
            
            % Calculate the residuals (the difference between each point and the local trend)
            residuals = y_data - moving_avg;
            
            % Calculate the standard deviation of these residuals
            residual_std = std(residuals);
            
            % Set the threshold for outlier detection
            threshold = std_multiplier * residual_std;
            
            % Find the indices of data points where the residual exceeds the threshold
            outlier_indices = find(abs(residuals) > threshold);
            
            % --- Step 3: Plot the Outliers ---
            if isempty(outlier_indices)
                uialert(app.UIFigure, 'No outliers were detected.', 'Analysis Complete');
            else
                % Get the coordinates of the outlier points
                x_outliers = x_data(outlier_indices);
                y_outliers = y_data(outlier_indices);
                
                % Keep the existing plot and add the new outlier plot on top
                hold(app.UIAxes, 'on');
                
                % Plot the outliers as red circles ('ro')
                % Increase marker size and line width for better visibility
                plot(app.UIAxes, x_outliers, y_outliers, 'ro', 'MarkerSize', 10, 'LineWidth', 2, 'DisplayName', 'Outliers');
                
                % Release the hold on the axes
                hold(app.UIAxes, 'off');
                
                % Optional: Add a legend to the plot
                legend(app.UIAxes, 'show');
            end

        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LoadDataButton = uibutton(app.LeftPanel, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.IdentifyOutliersButton = uibutton(app.LeftPanel, 'push');
            app.IdentifyOutliersButton.ButtonPushedFcn = createCallbackFcn(app, @IdentifyOutliersButtonPushed, true);
            app.IdentifyOutliersButton.Text = 'Identify Outliers';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end